classdef Zachet_Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
        point     matlab.ui.control.NumericEditField
        quantity  matlab.ui.control.NumericEditField
        EditFunc  matlab.ui.control.EditField
        Label_4   matlab.ui.control.Label
        Label_6   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Taylor    matlab.ui.control.Label
        FxLabel   matlab.ui.control.Label
        PxLabel   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
        Label_8   matlab.ui.control.Label
        Label_9   matlab.ui.control.Label
        Label_10  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                app.EditFunc.Enable = false;
                app.quantity.Enable = false;
                app.point.Enable = false;
                syms x
                
                a = app.point.Value;
                n = app.quantity.Value;
                f = str2sym(app.EditFunc.Value);
                T = taylor(f,x,a,'Order',n);
                app.Taylor.Text = string(T);
    
              
                fplot(app.UIAxes, f, [a-5, a+5], 'LineWidth', 1, "Color", "Blue");
                hold(app.UIAxes, "on");
                fplot(app.UIAxes, T, [a-5, a+5], 'LineWidth', 1, "Color", "Red", 'LineStyle',"--");
                hold(app.UIAxes, "on");
                fplot(app.UIAxes, f, [a-0.01, a+0.01], 'LineWidth', 2, "Color", "Black", "Marker",".", 'MarkerSize', 20);
                
                if (f == T)
                    warndlg('Недопустимая форма записи или тривиальный случай', 'Предупреждение');
                end

            catch
                errordlg('Недопустимая форма записи!','Ошибка');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начать';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Функция ';
            app.point = uieditfield(app.UIFigure, 'numeric');
            app.quantity = uieditfield(app.UIFigure, 'numeric');
            app.quantity.Value = 5;
            app.EditFunc = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Полином Тейлора';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Количество слагаемых';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Точка';
            app.Taylor = uilabel(app.UIFigure);
            app.Taylor.Text = '';
            app.FxLabel = uilabel(app.UIFigure);
            app.FxLabel.Text = 'F(x) =';
            app.PxLabel = uilabel(app.UIFigure);
            app.PxLabel.Text = 'P(x) =';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'График функции';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'График полинома';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zachet_Task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end