classdef ZTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Button            matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        quantity          matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        point             matlab.ui.control.NumericEditField
        FxEditFieldLabel  matlab.ui.control.Label
        EditFunc          matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Taylor            matlab.ui.control.Label
        TxLabel           matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             try
                app.EditFunc.Enable = false;
                app.quantity.Enable = false;
                app.point.Enable = false;
                syms x
                
                a = app.point.Value;
                n = app.quantity.Value;
                f = str2sym(app.EditFunc.Value);
                T = taylor(f,x,a,'Order',n);
                app.Taylor.Text = string(T);
    
              
                fplot(app.UIAxes, f, [a-5, a+5], 'LineWidth', 1, "Color", "Blue");
                hold(app.UIAxes, "on");
                fplot(app.UIAxes, T, [a-5, a+5], 'LineWidth', 1, "Color", "Red", 'LineStyle',"--");
                hold(app.UIAxes, "on");
                fplot(app.UIAxes, f, [a-0.01, a+0.01], 'LineWidth', 2, "Color", "Black", "Marker",".", 'MarkerSize', 20);
                
                if (f == T)
                    warndlg('Недопустимая форма записи или тривиальный случай', 'Предупреждение');
                end

            catch
                errordlg('Недопустимая форма записи!','Ошибка');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                app.EditFunc.Enable = true;
                app.quantity.Enable = true;
                app.point.Enable = true;
                app.point.Value = 0;
                app.EditFunc.Value = '';
                app.quantity.Value = 5;
                app.Taylor.Text = '';
                cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Графики функций')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Начать';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Слагаемых';
            app.quantity = uieditfield(app.UIFigure, 'numeric');
            app.quantity.Value = 5;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Точка';
            app.point = uieditfield(app.UIFigure, 'numeric');
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x) =';
            app.EditFunc = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Функция';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Приближение';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Taylor = uilabel(app.UIFigure);
            app.Taylor.Text = '';
            app.TxLabel = uilabel(app.UIFigure);
            app.TxLabel.Text = 'T(x) = ';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Очистить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZTask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end