classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        Button      matlab.ui.control.Button
        Panel       matlab.ui.container.Panel
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
          
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
        
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
 
             x = (0:0.01:4*pi);
             y1 = sin(x);
             y2 = cos(x);
             value1 = app.CheckBox.Value;
             value2 = app.CheckBox_2.Value;
             if (value1 == true && value2 == true)
                 plot(app.UIAxes, x, y2, 'LineWidth', 2, "Color", "Red");
                 hold(app.UIAxes);
                 plot(app.UIAxes, x, y1, 'LineWidth', 2, "Color", "Blue");
                 hold(app.UIAxes);
             end
             
             if (value1 == true && value2 == false)
                 cla(app.UIAxes);
                 plot(app.UIAxes, x, y1, 'LineWidth', 2, "Color", "Red");
             end
             
             if (value1 == false && value2 == true)
                 cla(app.UIAxes);
                 plot(app.UIAxes, x, y2, 'LineWidth', 2, "Color", "Blue");
             end
             
             if (value1 == false && value2 == false)
                 cla(app.UIAxes);
             end   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Графики функций')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Группа переключателей';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'График синуса';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'График косинуса';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end