classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Button       matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        yexpx2Label  matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        ysinxLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             x = -2:0.2:2;
             y = exp(-x.^2);
             Value1 = 1;
             assignin("base","Value1", Value1);
             
             plot(app.UIAxes, x, y, 'LineWidth', 2, "Color", "Red");
             hold(app.UIAxes);
             app.Button.Enable = false;
             app.Button_3.Enable = true;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             x1 = 0:0.1:4*pi;
             y1 = sin(x1);
             Value2 = 1;
             assignin('base','Value2', Value2);

             plot(app.UIAxes, x1, y1, 'LineWidth', 2, "Color", "Blue");
             hold(app.UIAxes);
             app.Button_2.Enable = false;
             app.Button_4.Enable = true;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            x1 = 0:0.1:4*pi;
            y1 = sin(x1);
            Value1 = 0;
            assignin("base","Value1", Value1);
            
            Value2 = evalin('base', 'Value2');
            cla(app.UIAxes);
            if (Value2 == 1)
                plot(app.UIAxes, x1, y1, 'LineWidth', 2, "Color", "Blue");
                hold(app.UIAxes);
            end
            app.Button_3.Enable = false;

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             x = -2:0.2:2;
             y = exp(-x.^2);
             Value2 = 0;
             assignin("base","Value2", Value2);
            
             Value1 = evalin('base', 'Value1');
             cla(app.UIAxes);
             if (Value1 == 1)
                plot(app.UIAxes, x, y, 'LineWidth', 2, "Color", "Red");  
                hold(app.UIAxes);
             end
             app.Button_4.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Графики функций')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'Очистить';
            app.yexpx2Label = uilabel(app.UIFigure);
            app.yexpx2Label.Text = 'y=exp(-x^2)';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Построить';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = 'Очистить';
            app.ysinxLabel = uilabel(app.UIFigure);
            app.ysinxLabel.Text = 'y = sin(x)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end